classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        fSpinner                    matlab.ui.control.Spinner
        fSpinnerLabel               matlab.ui.control.Label
        ButtonGroup_noise           matlab.ui.container.ButtonGroup
        Button_nonoise              matlab.ui.control.RadioButton
        Button_addnoise             matlab.ui.control.RadioButton
        Button_import_recording     matlab.ui.control.Button
        Spinner_recordtime          matlab.ui.control.Spinner
        Label_2                     matlab.ui.control.Label
        Button_stop                 matlab.ui.control.Button
        Button_clear                matlab.ui.control.Button
        Lamp_recordlight            matlab.ui.control.Lamp
        Button_record               matlab.ui.control.Button
        EditField_record_place      matlab.ui.control.EditField
        Label                       matlab.ui.control.Label
        FtSpinner                   matlab.ui.control.Spinner
        FtSpinnerLabel              matlab.ui.control.Label
        ApSpinner                   matlab.ui.control.Spinner
        ApSpinnerLabel              matlab.ui.control.Label
        AsSpinner                   matlab.ui.control.Spinner
        AsSpinnerLabel              matlab.ui.control.Label
        Fs2Spinner                  matlab.ui.control.Spinner
        Fs2SpinnerLabel             matlab.ui.control.Label
        Fs1SpinnerLabel             matlab.ui.control.Label
        Fs1Spinner                  matlab.ui.control.Spinner
        Fp2Spinner                  matlab.ui.control.Spinner
        Fp2SpinnerLabel             matlab.ui.control.Label
        Fp1Spinner                  matlab.ui.control.Spinner
        Fp1SpinnerLabel             matlab.ui.control.Label
        ButtonGroup_voiceselect     matlab.ui.container.ButtonGroup
        Button_new                  matlab.ui.control.RadioButton
        Button_old                  matlab.ui.control.RadioButton
        Button_play                 matlab.ui.control.Button
        ButtonGroup_fiteringselect  matlab.ui.container.ButtonGroup
        Button_low                  matlab.ui.control.RadioButton
        Button_high                 matlab.ui.control.RadioButton
        Button_band                 matlab.ui.control.RadioButton
        Button_fiteringdisplay      matlab.ui.control.Button
        UIAxes_new_time             matlab.ui.control.UIAxes
        UIAxes_filtering            matlab.ui.control.UIAxes
        UIAxes_new_frequence        matlab.ui.control.UIAxes
        UIAxes_old_frequence        matlab.ui.control.UIAxes
        UIAxes_old_time             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        mode2
        mode3
        mode4
        Fp
        Fp1
        Fp2
        Fs
        Fs1
        Fs2
        Ft
        As
        Ap
        wp
        ws
        wn
        n
        b
        a
        B
        A
        h
        w
        y
        y1
        t
        Y1
        fs
        N
        y2
        f1
        long%录音时间
        noise%噪声信号

        noise_flag%是否加噪声
        f%噪声频率
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_fiteringdisplay
        function Button_fiteringdisplayPushed(app, event)
            %赋值初始化
            app.Fp=app.Fp1Spinner.Value;
            app.Fp1=app.Fp1Spinner.Value;
            app.Fp2=app.Fp2Spinner.Value;

            app.Fs=app.Fs1Spinner.Value;
            app.Fs1=app.Fs1Spinner.Value;
            app.Fs2=app.Fs2Spinner.Value;

            app.As=app.AsSpinner.Value;
            app.Ap=app.ApSpinner.Value;
            app.Ft=app.FtSpinner.Value;

            app.mode2=app.Button_band.Value;
            app.mode3=app.Button_high.Value;
            app.mode4=app.Button_low.Value;

% 
%             %数字域频率转换模拟域频率
%             OmegaP=(2/T)*tan(app.wp/2);
%             OmegaS=(2/T)*tan(app.ws/2);
%             %求巴特沃斯阶次
%             [app.N,OmegaC]=buttord(OmegaP,OmegaS,app.Ap,app.As,'s');
%             [app.B,app.A]=butter(app.N,OmegaC,'s');
%             %双线性变换法
%             [bz,az]=bilinear(app.B,app.A,app.Ft);
%             [app.h,app.w]=freqz(bz,az,1024,'whole');
                        
            if app.mode2==1
                app.wp=2*pi*app.Fp/app.Ft;
                app.ws=2*pi*app.Fs/app.Ft;
                [app.n,app.wn]=ellipord(app.wp,app.ws,app.Ap,app.As,'s');
                [app.b,app.a]=ellip(app.n,app.Ap,app.As,app.wn,'s');
                [app.B,app.A]=bilinear(app.b,app.a,1);
                [app.h,app.w]=freqz(app.B,app.A);
%               滤波器特性  
                plot(app.UIAxes_filtering,app.w*app.Ft/pi/2,abs(app.h));
            end

            if app.mode3==1
                app.wp=2*pi*app.Fp/app.Ft;
                app.ws=2*pi*app.Fs/app.Ft;
                [app.n,app.wn]=ellipord(app.wp,app.ws,app.Ap,app.As,'s');
                [app.b,app.a]=ellip(app.n,app.Ap,app.As,app.wn,'high','s');
                [app.B,app.A]=bilinear(app.b,app.a,1);
                [app.h,app.w]=freqz(app.B,app.A);
%               滤波器特性                  
                plot(app.UIAxes_filtering,app.w*app.Ft/pi/2,abs(app.h));
            end

            if app.mode4==1
                app.wp=[2*pi*app.Fp1/app.Ft,2*pi*app.Fp2/app.Ft];
                app.ws=[2*pi*app.Fs1/app.Ft,2*pi*app.Fs2/app.Ft];
                [app.n,app.wn]=ellipord(app.wp,app.ws,app.Ap,app.As,'s');
                [app.b,app.a]=ellip(app.n,app.Ap,app.As,app.wn,'s');
                [app.B,app.A]=bilinear(app.b,app.a,1);
                [app.h,app.w]=freqz(app.B,app.A);
%               滤波器特性                  
                plot(app.UIAxes_filtering,app.w*app.Ft/pi/2,abs(app.h));
            end
            app.y=filter(app.B,app.A,app.y1);
            app.Y1=fft(app.y,app.N);
            plot(app.UIAxes_new_time,app.y);
            % stem(app.UIAxes_new_frequence,1:app.long*1000,app.Y1,'.');
            plot(app.UIAxes_new_frequence,app.f1,abs(app.Y1));
        end

        % Button pushed function: Button_import_recording
        function Button_import_recordingPushed(app, event)
            %导入录音
            app.noise_flag = app.Button_addnoise.Value;
            app.f = app.fSpinner.Value;
            %录音采样
            file = app.EditField_record_place.Value;
            [app.y1,app.fs] = audioread(file);
            app.N= length(app.y1);
            app.f1= (0:app.N-1)*app.fs/app.N;

            %加入噪声
            if app.noise_flag==1
                app.t = 1:1:app.N;
                app.noise = 200*sin(2*pi*app.f*app.t);
                app.y1=app.y1+app.noise';
            end

            app.y2 = fft (app.y1,app.N);
            plot(app.UIAxes_old_time,app.y1);
            plot(app.UIAxes_old_frequence,app.f1,abs(app.y2));
        end

        % Button pushed function: Button_record
        function Button_recordPushed(app, event)
            app.noise_flag = app.Button_addnoise.Value;
            app.f = app.fSpinner.Value;
            app.fs = 8000;  % 采样频率
            app.long = app.Spinner_recordtime.Value; % 时间长度(秒） 
            % 创建一个录音文件：fs =8000Hz, 16-bit, 单通道
            recorderObj = audiorecorder(app.fs, 16, 1);   
            app.Lamp_recordlight.Color='1,0,0';
            recordblocking(recorderObj, app.long); % 录音5秒钟
            stop(recorderObj);
            app.Lamp_recordlight.Color='0,1,0';

            app.y1 = getaudiodata(recorderObj);

            %加入噪声
            if app.noise_flag==1
                app.t = 1:1:app.long*app.fs;
                app.noise = 4000*sin(2*pi*app.f*app.t);
                app.y1=app.y1+app.noise';
            end
            
            ymax = max(abs(app.y1)); % 归一化
            app.y1 = app.y1/ymax;
            audiowrite('C:\Users\banana\Desktop\数字信号处理\zhsy\voice.wav', app.y1, app.fs); % 存储录音文件
            file = 'C:\Users\banana\Desktop\数字信号处理\zhsy\voice.wav';
            [app.y1,app.fs] = audioread(file);
            app.N = length(app.y1);
            app.f1= (0:app.N-1)*app.fs/app.N;
            app.y2 = fft (app.y1,app.N);
            plot(app.UIAxes_old_time,app.y1);
            plot(app.UIAxes_old_frequence,app.f1,abs(app.y2));

        end

        % Button pushed function: Button_play
        function Button_playPushed(app, event)
            if app.Button_old.Value==1
                sound(app.y1,app.fs)
            end
            if app.Button_new.Value==1
                sound(app.y,app.fs);
            end
        end

        % Button pushed function: Button_clear
        function Button_clearPushed(app, event)
             cla(app.UIAxes_old_time);
             cla(app.UIAxes_old_frequence);
             cla(app.UIAxes_new_time);
             cla(app.UIAxes_new_frequence);
             cla(app.UIAxes_filtering);
        end

        % Button pushed function: Button_stop
        function Button_stopPushed(app, event)
            if app.Button_old.Value==1
                clear sound
            end
            if app.Button_new.Value==1
                 clear sound
            end
        end

        % Value changed function: EditField_record_place
        function EditField_record_placeValueChanged(app, event)
            value = app.EditField_record_place.Value;
        end

        % Callback function
        function Button_addnoisePushed(app, event)


        end

        % Button down function: UIAxes_old_time
        function UIAxes_old_timeButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_old_time = uiaxes(app.UIFigure);
            title(app.UIAxes_old_time, '时域语音信号')
            xlabel(app.UIAxes_old_time, '时间')
            ylabel(app.UIAxes_old_time, '语音信号')
            zlabel(app.UIAxes_old_time, 'Z')
            app.UIAxes_old_time.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.UIAxes_old_time.ButtonDownFcn = createCallbackFcn(app, @UIAxes_old_timeButtonDown, true);
            app.UIAxes_old_frequence = uiaxes(app.UIFigure);
            title(app.UIAxes_old_frequence, '频域语音信号')
            xlabel(app.UIAxes_old_frequence, '频率')
            ylabel(app.UIAxes_old_frequence, '语音信号')
            zlabel(app.UIAxes_old_frequence, 'Z')
            app.UIAxes_old_frequence.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_old_frequence.XTickLabel = '';
            app.UIAxes_new_frequence = uiaxes(app.UIFigure);
            title(app.UIAxes_new_frequence, '滤波后频域语音信号')
            xlabel(app.UIAxes_new_frequence, '频率')
            ylabel(app.UIAxes_new_frequence, '语音信号')
            zlabel(app.UIAxes_new_frequence, 'Z')
            app.UIAxes_new_frequence.XTickLabel = {'0'; '1600'; '3200'; '4800'; '6400'; '8000'};
            app.UIAxes_filtering = uiaxes(app.UIFigure);
            title(app.UIAxes_filtering, '滤波器特性')
            xlabel(app.UIAxes_filtering, '频率')
            ylabel(app.UIAxes_filtering, '幅度')
            zlabel(app.UIAxes_filtering, 'Z')
            app.UIAxes_filtering.XTickLabel = {'0'; '2500'; '5000'};
            app.UIAxes_filtering.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_filtering.YTickLabel = {'0'; '0.12'; '0.24'; '0.36'; '0.48'; '0.60'; '0.72'; '0.84'; '0.96'; '1.08'; '1.20'};
            app.UIAxes_new_time = uiaxes(app.UIFigure);
            title(app.UIAxes_new_time, '滤波后时域语音信号')
            xlabel(app.UIAxes_new_time, '时间')
            ylabel(app.UIAxes_new_time, '语音信号')
            zlabel(app.UIAxes_new_time, 'Z')
            app.UIAxes_new_time.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.Button_fiteringdisplay = uibutton(app.UIFigure, 'push');
            app.Button_fiteringdisplay.ButtonPushedFcn = createCallbackFcn(app, @Button_fiteringdisplayPushed, true);
            app.Button_fiteringdisplay.Text = '滤波并显示图像';
            app.ButtonGroup_fiteringselect = uibuttongroup(app.UIFigure);
            app.ButtonGroup_fiteringselect.Title = '滤波器';
            app.Button_band = uiradiobutton(app.ButtonGroup_fiteringselect);
            app.Button_band.Text = '低通滤波器';
            app.Button_band.Value = true;
            app.Button_high = uiradiobutton(app.ButtonGroup_fiteringselect);
            app.Button_high.Text = '高通滤波器';
            app.Button_low = uiradiobutton(app.ButtonGroup_fiteringselect);
            app.Button_low.Text = '带通滤波器';
            app.Button_play = uibutton(app.UIFigure, 'push');
            app.Button_play.ButtonPushedFcn = createCallbackFcn(app, @Button_playPushed, true);
            app.Button_play.Text = '播放';
            app.ButtonGroup_voiceselect = uibuttongroup(app.UIFigure);
            app.ButtonGroup_voiceselect.Title = '语音选择';
            app.Button_old = uiradiobutton(app.ButtonGroup_voiceselect);
            app.Button_old.Text = '原始语音';
            app.Button_old.Value = true;
            app.Button_new = uiradiobutton(app.ButtonGroup_voiceselect);
            app.Button_new.Text = '滤波后语音';
            app.Fp1SpinnerLabel = uilabel(app.UIFigure);
            app.Fp1SpinnerLabel.Text = 'Fp1';
            app.Fp1Spinner = uispinner(app.UIFigure);
            app.Fp1Spinner.Value = 1200;
            app.Fp2SpinnerLabel = uilabel(app.UIFigure);
            app.Fp2SpinnerLabel.Text = 'Fp2';
            app.Fp2Spinner = uispinner(app.UIFigure);
            app.Fp2Spinner.Value = 3000;
            app.Fs1Spinner = uispinner(app.UIFigure);
            app.Fs1Spinner.Value = 1000;
            app.Fs1SpinnerLabel = uilabel(app.UIFigure);
            app.Fs1SpinnerLabel.Text = 'Fs1';
            app.Fs2SpinnerLabel = uilabel(app.UIFigure);
            app.Fs2SpinnerLabel.Text = 'Fs2';
            app.Fs2Spinner = uispinner(app.UIFigure);
            app.Fs2Spinner.Value = 3200;
            app.AsSpinnerLabel = uilabel(app.UIFigure);
            app.AsSpinnerLabel.Text = 'As';
            app.AsSpinner = uispinner(app.UIFigure);
            app.AsSpinner.Value = 100;
            app.ApSpinnerLabel = uilabel(app.UIFigure);
            app.ApSpinnerLabel.Text = 'Ap';
            app.ApSpinner = uispinner(app.UIFigure);
            app.ApSpinner.Value = 1;
            app.FtSpinnerLabel = uilabel(app.UIFigure);
            app.FtSpinnerLabel.Text = 'Ft';
            app.FtSpinner = uispinner(app.UIFigure);
            app.FtSpinner.Value = 8000;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '音频位置';
            app.EditField_record_place = uieditfield(app.UIFigure, 'text');
            app.EditField_record_place.ValueChangedFcn = createCallbackFcn(app, @EditField_record_placeValueChanged, true);
            app.EditField_record_place.Value = 'C:\Users\banana\Desktop\数字信号处理\zhsy\record.mp3';
            app.Button_record = uibutton(app.UIFigure, 'push');
            app.Button_record.ButtonPushedFcn = createCallbackFcn(app, @Button_recordPushed, true);
            app.Button_record.Text = '录制语音';
            app.Lamp_recordlight = uilamp(app.UIFigure);
            app.Button_clear = uibutton(app.UIFigure, 'push');
            app.Button_clear.ButtonPushedFcn = createCallbackFcn(app, @Button_clearPushed, true);
            app.Button_clear.Text = '清空';
            app.Button_stop = uibutton(app.UIFigure, 'push');
            app.Button_stop.ButtonPushedFcn = createCallbackFcn(app, @Button_stopPushed, true);
            app.Button_stop.Text = '停止';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '时长';
            app.Spinner_recordtime = uispinner(app.UIFigure);
            app.Spinner_recordtime.Value = 5;
            app.Button_import_recording = uibutton(app.UIFigure, 'push');
            app.Button_import_recording.ButtonPushedFcn = createCallbackFcn(app, @Button_import_recordingPushed, true);
            app.Button_import_recording.Text = '导入录音';
            app.ButtonGroup_noise = uibuttongroup(app.UIFigure);
            app.ButtonGroup_noise.Title = '噪声';
            app.Button_addnoise = uiradiobutton(app.ButtonGroup_noise);
            app.Button_addnoise.Text = '加';
            app.Button_addnoise.Value = true;
            app.Button_nonoise = uiradiobutton(app.ButtonGroup_noise);
            app.Button_nonoise.Text = '不加';
            app.fSpinnerLabel = uilabel(app.UIFigure);
            app.fSpinnerLabel.Text = 'f';
            app.fSpinner = uispinner(app.UIFigure);
            app.fSpinner.Value = 1000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end